classdef image_segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AdvancePanel                    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        FillingDetectionPanel           matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GreenThresholdEditField_2Label  matlab.ui.control.Label
        GreenThresholdEditField_2       matlab.ui.control.NumericEditField
        AutoButton_2                    matlab.ui.control.Button
        RedThresholdEditField_2         matlab.ui.control.NumericEditField
        BlueThresholdEditField_2        matlab.ui.control.NumericEditField
        RedThresholdEditField_2Label    matlab.ui.control.Label
        BlueThresholdEditField_2Label   matlab.ui.control.Label
        EdgeDetectionPanel              matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        BlueThresholdEditField          matlab.ui.control.EditField
        BlueThresholdEditFieldLabel     matlab.ui.control.Label
        GreenThresholdEditField         matlab.ui.control.EditField
        GreenThresholdEditFieldLabel    matlab.ui.control.Label
        RedThresholdEditField           matlab.ui.control.EditField
        RedThresholdEditFieldLabel      matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        AutoButton                      matlab.ui.control.Button
        GridLayout                      matlab.ui.container.GridLayout
        EdgeDetectionDropDown           matlab.ui.control.DropDown
        EdgeDetectionDropDownLabel      matlab.ui.control.Label
        AdvanceButton                   matlab.ui.control.StateButton
        FillingDetectionDropDown        matlab.ui.control.DropDown
        FillingDetectionDropDownLabel   matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        OriginalImageContainer          matlab.ui.control.UIAxes
        SegmentedImageContainer         matlab.ui.control.UIAxes
        FillingImageContainer           matlab.ui.control.UIAxes
        EdgeImageContainer              matlab.ui.control.UIAxes
        BrowseImageButton               matlab.ui.control.Button
    end

    properties (Access = public)
        original_image
        edge_image
        filling_image
        segmented_image
        filling_mode
    end

    methods (Access = public)
        function updateimage(app, fname)
            try
                app.original_image = imread(fname);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            displayImages(app)
        end
        
        function displayImages(app)
            if isempty(app.original_image) 
                return 
            end

            imagesc(app.OriginalImageContainer, app.original_image);
            displayEdge(app);
            displayFilling(app);
            displaySegmented(app);
        end

        function displayEdge(app)
            if isempty(app.original_image) 
                return 
            end

            image = app.original_image;
            mode = app.EdgeDetectionDropDown.Value;
            threshold = 200;
            type = 'gray';
            app.edge_image = detectEdge(image, mode, threshold, type);
            imagesc(app.EdgeImageContainer, app.edge_image);
        end

        function displayFilling(app)
            if isempty(app.original_image) 
                return 
            end

            image = app.original_image;
            mode = app.filling_mode(app.FillingDetectionDropDown.Value);
            min_threshold = 170;
            max_threshold = 255;
            app.filling_image = detectColor(image, mode, min_threshold, max_threshold);
            imagesc(app.FillingImageContainer, app.filling_image);
        end

        function displaySegmented(app)
            if isempty(app.original_image) || isempty(app.edge_image) || isempty(app.filling_image)
                return 
            end

            mask = app.edge_image | app.filling_image;
            mask_image = ~cat(3, mask, mask, mask);
            app.segmented_image = app.original_image;
            app.segmented_image(mask_image) = 0;
            imagesc(app.SegmentedImageContainer, app.segmented_image);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.OriginalImageContainer.Colormap = gray(256);
            axis(app.OriginalImageContainer, 'image');
            app.EdgeImageContainer.Colormap = gray(256);
            axis(app.EdgeImageContainer, 'image');
            app.FillingImageContainer.Colormap = gray(256);
            axis(app.FillingImageContainer, 'image');
            app.SegmentedImageContainer.Colormap = gray(256);
            axis(app.SegmentedImageContainer, 'image');

            keySet = {'Red', 'Green', 'Blue', 'RGB'};
            valueSet = [1 2 3 0];
            app.filling_mode = containers.Map(keySet, valueSet);
           
            app.AdvancePanel.Visible = 'off';
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: EdgeDetectionDropDown
        function EdgeDetectionDropDownValueChanged(app, event)
            displayEdge(app);
            displaySegmented(app);
        end

        % Value changed function: FillingDetectionDropDown
        function FillingDetectionDropDownValueChanged(app, event)
            displayFilling(app);
            displaySegmented(app);
        end

        % Value changed function: AdvanceButton
        function AdvanceButtonValueChanged(app, event)
            value = app.AdvanceButton.Value;
            
            column = app.GridLayout.ColumnWidth;
            if value
                app.GridLayout.ColumnWidth = [column 20 300];
                app.AdvancePanel.Parent = app.GridLayout;
                colnum = length(app.GridLayout.ColumnWidth);
                rownum = length(app.GridLayout.RowHeight);
                app.AdvancePanel.Layout.Column = [(colnum-1) colnum];
                app.AdvancePanel.Layout.Row = [2 (rownum-1)];
                app.AdvancePanel.Visible = 'on';
                disp(app.GridLayout.Children);
            else
                app.AdvancePanel.Parent = [];
                app.AdvancePanel.Visible = 'off';
                app.GridLayout.ColumnWidth = column(1:end-2);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BrowseImageButton = uibutton(app.GridLayout, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.EdgeImageContainer = uiaxes(app.GridLayout2);
            title(app.EdgeImageContainer, 'Edge Image')
            zlabel(app.EdgeImageContainer, 'Z')
            app.EdgeImageContainer.XLim = [0 1];
            app.EdgeImageContainer.XTick = [];
            app.EdgeImageContainer.YTick = [];
            app.FillingImageContainer = uiaxes(app.GridLayout2);
            title(app.FillingImageContainer, 'Filling Image')
            zlabel(app.FillingImageContainer, 'Z')
            app.FillingImageContainer.XLim = [0 1];
            app.FillingImageContainer.XTick = [];
            app.FillingImageContainer.YTick = [];
            app.SegmentedImageContainer = uiaxes(app.GridLayout2);
            title(app.SegmentedImageContainer, 'Segmented Image')
            zlabel(app.SegmentedImageContainer, 'Z')
            app.SegmentedImageContainer.XLim = [0 1];
            app.SegmentedImageContainer.XTick = [];
            app.SegmentedImageContainer.YTick = [];
            app.OriginalImageContainer = uiaxes(app.GridLayout2);
            title(app.OriginalImageContainer, 'Original Image')
            app.OriginalImageContainer.XLim = [0 1];
            app.OriginalImageContainer.XTick = [];
            app.OriginalImageContainer.YTick = [];
            app.OriginalImageContainer.ZTick = [];
            app.FillingDetectionDropDownLabel = uilabel(app.GridLayout);
            app.FillingDetectionDropDownLabel.Text = 'Filling Detection';
            app.FillingDetectionDropDown = uidropdown(app.GridLayout);
            app.FillingDetectionDropDown.Items = {'Red', 'Green', 'Blue', 'RGB'};
            app.FillingDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @FillingDetectionDropDownValueChanged, true);
            app.FillingDetectionDropDown.Value = 'Red';
            app.AdvanceButton = uibutton(app.GridLayout, 'state');
            app.AdvanceButton.ValueChangedFcn = createCallbackFcn(app, @AdvanceButtonValueChanged, true);
            app.AdvanceButton.Text = 'Advance';
            app.EdgeDetectionDropDownLabel = uilabel(app.GridLayout);
            app.EdgeDetectionDropDownLabel.Text = 'Edge Detection';
            app.EdgeDetectionDropDown = uidropdown(app.GridLayout);
            app.EdgeDetectionDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionDropDownValueChanged, true);
            app.EdgeDetectionDropDown.Value = 'Laplace';
            app.AdvancePanel = uipanel(app.UIFigure);
            app.AdvancePanel.Title = 'Advance Option';
            app.GridLayout3 = uigridlayout(app.AdvancePanel);
            app.EdgeDetectionPanel = uipanel(app.GridLayout3);
            app.EdgeDetectionPanel.Title = 'Edge Detection';
            app.GridLayout4 = uigridlayout(app.EdgeDetectionPanel);
            app.AutoButton = uibutton(app.GridLayout4, 'push');
            app.AutoButton.Text = 'Auto';
            app.DropDownLabel = uilabel(app.GridLayout4);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout4);
            app.RedThresholdEditFieldLabel = uilabel(app.GridLayout4);
            app.RedThresholdEditFieldLabel.Text = 'Red Threshold';
            app.RedThresholdEditField = uieditfield(app.GridLayout4, 'text');
            app.RedThresholdEditField.Value = '0 255';
            app.GreenThresholdEditFieldLabel = uilabel(app.GridLayout4);
            app.GreenThresholdEditFieldLabel.Text = 'Green Threshold';
            app.GreenThresholdEditField = uieditfield(app.GridLayout4, 'text');
            app.GreenThresholdEditField.Value = '0 255';
            app.BlueThresholdEditFieldLabel = uilabel(app.GridLayout4);
            app.BlueThresholdEditFieldLabel.Text = 'Blue Threshold';
            app.BlueThresholdEditField = uieditfield(app.GridLayout4, 'text');
            app.BlueThresholdEditField.Value = '0 255';
            app.FillingDetectionPanel = uipanel(app.GridLayout3);
            app.FillingDetectionPanel.Title = 'Filling Detection';
            app.GridLayout5 = uigridlayout(app.FillingDetectionPanel);
            app.BlueThresholdEditField_2Label = uilabel(app.GridLayout5);
            app.BlueThresholdEditField_2Label.Text = 'Blue Threshold';
            app.RedThresholdEditField_2Label = uilabel(app.GridLayout5);
            app.RedThresholdEditField_2Label.Text = 'Red Threshold';
            app.BlueThresholdEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.BlueThresholdEditField_2.Limits = [0 255];
            app.BlueThresholdEditField_2.Editable = 'off';
            app.RedThresholdEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.RedThresholdEditField_2.Limits = [0 255];
            app.RedThresholdEditField_2.Editable = 'off';
            app.AutoButton_2 = uibutton(app.GridLayout5, 'push');
            app.AutoButton_2.Text = 'Auto';
            app.GreenThresholdEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.GreenThresholdEditField_2.Limits = [0 255];
            app.GreenThresholdEditField_2.Editable = 'off';
            app.GreenThresholdEditField_2Label = uilabel(app.GridLayout5);
            app.GreenThresholdEditField_2Label.Text = 'Green Threshold';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end